classdef Block_GUI_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TestConnectionButton            matlab.ui.control.Button
        CheckBox_2                      matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.StateButton
        SendToArduinoButton             matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        CopyButton                      matlab.ui.control.Button
        SLabel                          matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        ELabel                          matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        w5u                             matlab.ui.control.EditField
        s1u                             matlab.ui.control.EditField
        s2u                             matlab.ui.control.EditField
        s3u                             matlab.ui.control.EditField
        e5u                             matlab.ui.control.EditField
        n3u                             matlab.ui.control.EditField
        n2u                             matlab.ui.control.EditField
        n1u                             matlab.ui.control.EditField
        w6l                             matlab.ui.control.EditField
        w5l                             matlab.ui.control.EditField
        w4l                             matlab.ui.control.EditField
        s1l                             matlab.ui.control.EditField
        s2l                             matlab.ui.control.EditField
        s3l                             matlab.ui.control.EditField
        e6l                             matlab.ui.control.EditField
        e5l                             matlab.ui.control.EditField
        e4l                             matlab.ui.control.EditField
        n3l                             matlab.ui.control.EditField
        n2l                             matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
        n1l                             matlab.ui.control.EditField
        CurrentBlockInfoEditField       matlab.ui.control.EditField
        CurrentBlockInfoEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Uno % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%                         COM = "COM3";                  % Set this to match Arduino Com port
%                         BaudRate = 9600;               % Set this to match Arduino baud rate
%                         app.Uno = serialport(COM,BaudRate); % Device
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.n1l.Value = "x";
            app.n2l.Value = "x";
            app.n3l.Value = "x";
            app.n1u.Value = "x";
            app.n2u.Value = "x";
            app.n3u.Value = "x";
            app.e4l.Value = "x";
            app.e5l.Value = "x";
            app.e6l.Value = "x";
            app.e5u.Value = "x";
            app.w4l.Value = "x";
            app.w5l.Value = "x";
            app.w6l.Value = "x";
            app.w5u.Value = "x";
            app.s1l.Value = "x";
            app.s2l.Value = "x";
            app.s3l.Value = "x";
            app.s1u.Value = "x";
            app.s2u.Value = "x";
            app.s3u.Value = "x";

            blockVec = [app.n1l app.n2l app.n3l app.n1u app.n2u app.n3u app.e4l app.e5l app.e6l app.e5u app.w4l app.w5l app.w6l app.w5u app.s1l app.s2l app.s3l app.s1u app.s2u app.s3u];
            for i = 1:length(blockVec)
                set(blockVec(i),"BackgroundColor","white");
            end

            app.CheckBox.Value = 0;
            app.CurrentBlockInfoEditField.Value = "";
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            clipboard("copy",app.CurrentBlockInfoEditField.Value)
            app.CheckBox.Value = 1;
            pause(1);
            app.CheckBox.Value = 0;
        end

        % Button pushed function: SendToArduinoButton
        function SendToArduinoButtonPushed(app, event)
%                         To_Send = app.CurrentBlockInfoEditField.Value;
%                         writeline(app.Uno,To_Send);
%                         app.CheckBox_2.Value = 1;
%                         pause(1);
%                         app.CheckBox_2.Value = 0;
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            app.n1l.Value = "x";
            app.n2l.Value = "x";
            app.n3l.Value = "x";
            app.n1u.Value = "x";
            app.n2u.Value = "x";
            app.n3u.Value = "x";
            app.e4l.Value = "x";
            app.e5l.Value = "x";
            app.e6l.Value = "x";
            app.e5u.Value = "x";
            app.w4l.Value = "x";
            app.w5l.Value = "x";
            app.w6l.Value = "x";
            app.w5u.Value = "x";
            app.s1l.Value = "x";
            app.s2l.Value = "x";
            app.s3l.Value = "x";
            app.s1u.Value = "x";
            app.s2u.Value = "x";
            app.s3u.Value = "x";
            app.CurrentBlockInfoEditField.Value = "";

            while (app.RunButton.Value == 1)
                info = "";
                locVec = ["n1l" "n2l" "n3l" "w4u" "n2u" "e4u" "e4l" "e5l" "e6l" "e5u" "w4l" "w5l" "w6l" "w5u" "s1l" "s2l" "s3l" "w6u" "s2u" "e6u"  ];
                colorsVec = [app.n1l.Value app.n2l.Value app.n3l.Value app.n1u.Value app.n2u.Value app.n3u.Value app.e4l.Value app.e5l.Value app.e6l.Value app.e5u.Value app.w4l.Value app.w5l.Value app.w6l.Value app.w5u.Value app.s1l.Value app.s2l.Value app.s3l.Value app.s1u.Value app.s2u.Value app.s3u.Value];
                blockVec = [app.n1l app.n2l app.n3l app.n1u app.n2u app.n3u app.e4l app.e5l app.e6l app.e5u app.w4l app.w5l app.w6l app.w5u app.s1l app.s2l app.s3l app.s1u app.s2u app.s3u];

                j = 1;
                num_blocks = 0;
                for i = 1:length(colorsVec)
                    if strcmp(colorsVec(i),"r") || strcmp(colorsVec(i),"y") || strcmp(colorsVec(i),"b")
                        infoVec(j) = locVec(i) + colorsVec(i);
                        info = info + infoVec(j);
                        j = j + 1;
                        num_blocks = num_blocks + 1;
                            if strcmp(colorsVec(i),"r")
                                set(blockVec(i),"BackgroundColor","red");
                            elseif strcmp(colorsVec(i),"y")
                                set(blockVec(i),"BackgroundColor","yellow");
                            elseif strcmp(colorsVec(i),"b")
                                set(blockVec(i),"BackgroundColor","blue");
                            end
                    else 
                        set(blockVec(i),"BackgroundColor","white");
                        colorVec(i) = "x";
                    end
                end
                
                app.CurrentBlockInfoEditField.Value = "!" + int2str(num_blocks) + "!" + info;
                drawnow;
            end
            
            blockVec = [app.n1l app.n2l app.n3l app.n1u app.n2u app.n3u app.e4l app.e5l app.e6l app.e5u app.w4l app.w5l app.w6l app.w5u app.s1l app.s2l app.s3l app.s1u app.s2u app.s3u];

                if (app.RunButton.Value == 0)
                    for i = 1:length(blockVec)
                        set(blockVec(i),"BackgroundColor","white");
                    end
                end
        end

        % Button pushed function: TestConnectionButton
        function TestConnectionButtonPushed(app, event)
%                         Test = "Hello Demo Bot, this is block GUI. Connection is confirmed.";
%                         writeline(app.Uno,Test);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentBlockInfoEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentBlockInfoEditFieldLabel.Text = 'Current Block Info';
            app.CurrentBlockInfoEditField = uieditfield(app.UIFigure, 'text');
            app.n1l = uieditfield(app.UIFigure, 'text');
            app.n1l.Placeholder = 'x';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Chassis.JPG';
            app.n2l = uieditfield(app.UIFigure, 'text');
            app.n2l.Placeholder = 'x';
            app.n3l = uieditfield(app.UIFigure, 'text');
            app.n3l.Placeholder = 'x';
            app.e4l = uieditfield(app.UIFigure, 'text');
            app.e4l.Placeholder = 'x';
            app.e5l = uieditfield(app.UIFigure, 'text');
            app.e5l.Placeholder = 'x';
            app.e6l = uieditfield(app.UIFigure, 'text');
            app.e6l.Placeholder = 'x';
            app.s3l = uieditfield(app.UIFigure, 'text');
            app.s3l.Placeholder = 'x';
            app.s2l = uieditfield(app.UIFigure, 'text');
            app.s2l.Placeholder = 'x';
            app.s1l = uieditfield(app.UIFigure, 'text');
            app.s1l.Placeholder = 'x';
            app.w4l = uieditfield(app.UIFigure, 'text');
            app.w4l.Placeholder = 'x';
            app.w5l = uieditfield(app.UIFigure, 'text');
            app.w5l.Placeholder = 'x';
            app.w6l = uieditfield(app.UIFigure, 'text');
            app.w6l.Placeholder = 'x';
            app.n1u = uieditfield(app.UIFigure, 'text');
            app.n1u.Placeholder = 'x';
            app.n2u = uieditfield(app.UIFigure, 'text');
            app.n2u.Placeholder = 'x';
            app.n3u = uieditfield(app.UIFigure, 'text');
            app.n3u.Placeholder = 'x';
            app.e5u = uieditfield(app.UIFigure, 'text');
            app.e5u.Placeholder = 'x';
            app.s3u = uieditfield(app.UIFigure, 'text');
            app.s3u.Placeholder = 'x';
            app.s2u = uieditfield(app.UIFigure, 'text');
            app.s2u.Placeholder = 'x';
            app.s1u = uieditfield(app.UIFigure, 'text');
            app.s1u.Placeholder = 'x';
            app.w5u = uieditfield(app.UIFigure, 'text');
            app.w5u.Placeholder = 'x';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Text = 'E';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S';
            app.CopyButton = uibutton(app.UIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.SendToArduinoButton = uibutton(app.UIFigure, 'push');
            app.SendToArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @SendToArduinoButtonPushed, true);
            app.SendToArduinoButton.Text = 'Send To Arduino';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.TestConnectionButton = uibutton(app.UIFigure, 'push');
            app.TestConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @TestConnectionButtonPushed, true);
            app.TestConnectionButton.Text = 'Test Connection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Block_GUI_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end